classdef waterSamplerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenu     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Image        matlab.ui.control.Image
        LogsText     matlab.ui.control.TextArea
        LogsPanel    matlab.ui.container.Panel
        WebcamPanel  matlab.ui.container.Panel
        StopButton   matlab.ui.control.Button
        SCRAMButton  matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
    end

    properties (Access = public)
        % Declare null variables here
        a;
        cam;
        ranFromWaterSamplerScript;
        centralTimer;
        internalTimer;

        % Declare variables here
        stopRequested = false;
        startVision = false;
        wS_State = "Stopped";
        action = 0;
    end

    methods (Access = private)
        
        % Sends a log to the log GUI area
        function wS_Log(app, level, message)
            app.LogsText.Value = [sprintf("%7.3f - [%s] %s", toc(app.centralTimer), level, message); app.LogsText.Value];
        end

        % The code for the vision sub-system
        % app - passes in the app object for use of variables
        function wS_Vision(app)
            % Logs that the vision system started.
            wS_Log(app, "INFO", "Vision system started.");

            % VISION CODE GOES HERE

        end
        
        % The code for the rotational base sub-system
        % app - passes in the app object for use of variables
        % distance - the distance to rotate clockwise
        % speed - the speed at which to travel (1.00 = top speed)
        function wS_Rotational(app, distance, speed)
            % Logs that the rotational base system was told to move.
            wS_Log(app, "INFO", "Rotational system started.");

            % ROTATIONAL BASE CODE GOES HERE

        end

        % The code for the pipette sub-system
        % app - passes in the app object for use of variables
        % distance - the distance to move towards the pipette
        % speed - the speed at which to travel (1.00 = top speed)
        function wS_Pipette(app, distance, speed)
            % Logs that the pipette system was told to move.
            wS_Log(app, "INFO", "Pipette system started.");

            % PIPETTE SQUEEZE CODE GOES HERE

        end

        % The code for the rotational base sub-system
        % app - passes in the app object for use of variables
        % distance - the distance to travel upwards
        % speed - the speed at which to travel (1.00 = top speed)
        function wS_Lift(app, distance, speed)
            % Logs that the lift system was told to move.
            wS_Log(app, "INFO", "Lift system started.");

            % LIFT WITH STAND CODE GOES HERE

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.centralTimer = tic; % Starts the timer

            app.StartButton.BackgroundColor = [0.39, 0.83, 0.07];
            app.SCRAMButton.BackgroundColor = [1.00, 0.00, 0.00];
            app.StopButton.BackgroundColor = [0.00, 0.45, 0.74];

            % Tries to evaluate a variable in the workspace
            try
                app.ranFromWaterSamplerScript = evalin("base", "ranFromWaterSamplerScript");
            catch exception
                % Nicely rethrows the exception
                app.LogsText.FontColor = [1.00, 0.00, 0.00];
                wS_Log(app, "ERR", "GUI ran without script. Run the script 'waterSampler.m' instead.");
                rethrow(exception);
            end

            try
                % Evaluates the value of the variable called "cam" in the workspace.
                % Then, assigns that value to app.cam
                app.cam = evalin("base", "cam");
                %app.a = evalin("base", "a");

            catch exception
                app.LogsText.FontColor = [1.00, 0.00, 0.00];
                wS_Log(app, "ERR", "Failed to evaluate one or more variables from Workshop. (Do they exist before evaluation?)");
                rethrow(exception);
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~(app.wS_State == "Started")
                app.wS_State = "Started"; % If not already, then do so

                % Sets the background color to dark green
                app.GridLayout.BackgroundColor = [0.00, 0.72, 0.00];
                app.StartButton.BackgroundColor = [0.47, 0.47, 0.47];
                app.SCRAMButton.BackgroundColor = [1.00, 0.00, 0.00];
                app.StopButton.BackgroundColor = [0.00, 0.45, 0.74];
    
                wS_Log(app, "USER", "Starting the water sampler...");

                wS_Log(app, "INFO", "Restarting the timer...");
                app.centralTimer = tic; % Restarts the timer
    
                app.internalTimer = tic;
                app.action = app.action + 1;
                try
                    while ((~app.stopRequested) && (~app.MainMenu.BeingDeleted) && ((toc(app.centralTimer)) < 300) && (app.action < 100))
                        if (app.action == 1)
                            wS_Log(app, "EVNT", "Action 1");
                            app.action = app.action + 1;
                            app.internalTimer = tic;
                        elseif ((app.action == 2) && (toc(app.internalTimer) > 5.000))
                            wS_Log(app, "EVNT", "Action 2");
                            app.action = app.action + 1;
                            app.internalTimer = tic;
                        elseif ((app.action == 3) && (toc(app.internalTimer) > 5.000))
                            wS_Log(app, "EVNT", "Action 3");
                            app.action = app.action + 1;
                            app.internalTimer = tic;
                        end
                        app.Image.ImageSource = snapshot(app.cam);
                        pause(0.100);
                    end
                catch exception
                    % If it crashes, rethrow the exception
                    % UNLESS it is the invalid handle error
                    if ~(strcmp(exception.identifier, "MATLAB:class:InvalidHandle"))
                        rethrow(exception);
                    end
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~(app.wS_State == "Stopped")
                app.stopRequested = true;
                pause(1.000);
                app.wS_State = "Stopped"; % If not already, then do so

                % Sets the background color to dark blue
                app.GridLayout.BackgroundColor = [0.00, 0.34, 0.56];
                app.StartButton.BackgroundColor = [0.39, 0.83, 0.07];
                app.SCRAMButton.BackgroundColor = [1.00, 0.00, 0.00];
                app.StopButton.BackgroundColor = [0.47, 0.47, 0.47];

                wS_Log(app, "USER", "Stopping the water sampler...");
    
                % Stops the webcam
                wS_Log(app, "INFO", "Stopping webcam...");
                app.startVision = false;
                pause(0.250); % Pause to ensure it does not call a deleted object
                delete(app.cam);
    
                % Returns motors to home

                wS_Log(app, "INFO", "Stopping app...");
                pause(3.000);
                delete(app); % Closes the app
            end
        end

        % Button pushed function: SCRAMButton
        function SCRAMButtonPushed(app, event)
            if ~(app.wS_State == "SCRAM")
                app.stopRequested = true;
                pause(1.000);
                app.wS_State = "SCRAM"; % If not already, then do so
                
                app.GridLayout.BackgroundColor = [0.80, 0.00, 0.00];
                app.StartButton.BackgroundColor = [0.39, 0.83, 0.07];
                app.SCRAMButton.BackgroundColor = [0.47, 0.47, 0.47];
                app.StopButton.BackgroundColor = [0.00, 0.45, 0.74];
                wS_Log(app, "USER", "SCRAMing the water sampler...");

                % Tell all motors to stop moving

            end
        end

        % Close request function: MainMenu
        function MainMenuCloseRequest(app, event)
            % When the red "X" is pressed, the water sampler returns to
            % a reusable position, then closes.
            if (app.wS_State == "Stopped")
                delete(app); % If already stopped, close the app
            else
                StopButtonPushed(app); % Stop, then close the app
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenu = uifigure('Visible', 'off');
            app.MainMenu.Name = 'Water Sampler  -  EGR 103-005-005';
            app.MainMenu.Icon = 'WaterDrop.png';
            app.MainMenu.CloseRequestFcn = createCallbackFcn(app, @MainMenuCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainMenu);
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Starts the water sampler'};
            app.StartButton.Text = 'Start';
            app.SCRAMButton = uibutton(app.GridLayout, 'push');
            app.SCRAMButton.ButtonPushedFcn = createCallbackFcn(app, @SCRAMButtonPushed, true);
            app.SCRAMButton.Tooltip = {'Stops the water sampler instantly without resetting motor positions'};
            app.SCRAMButton.Text = 'SCRAM';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tooltip = {'Stops the water sampler and resets to default position'};
            app.StopButton.Text = 'Stop';
            app.WebcamPanel = uipanel(app.GridLayout);
            app.WebcamPanel.Title = 'Webcam';
            app.LogsPanel = uipanel(app.GridLayout);
            app.LogsPanel.Title = 'Logs';
            app.LogsText = uitextarea(app.GridLayout);
            app.LogsText.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.MainMenu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waterSamplerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenu)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenu)
        end
    end
end